classdef GUI_graficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        BajadaAxes           matlab.ui.control.UIAxes
        SubidaAxes           matlab.ui.control.UIAxes
        GridLayout3          matlab.ui.container.GridLayout
        BienvenidoaSRSLabel  matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        GrficasButtonGroup   matlab.ui.container.ButtonGroup
        TobilloButton        matlab.ui.control.RadioButton
        RodillaButton        matlab.ui.control.RadioButton
        CaderaButton         matlab.ui.control.RadioButton
        GridLayout6          matlab.ui.container.GridLayout
        Label_4              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        FechaLabel           matlab.ui.control.Label
        PesokgLabel          matlab.ui.control.Label
        EdadLabel            matlab.ui.control.Label
        PacienteLabel        matlab.ui.control.Label
        GridLayout4          matlab.ui.container.GridLayout
        AvatarButton         matlab.ui.control.Button
        InformeButton        matlab.ui.control.Button
        Image                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: GrficasButtonGroup
        function GrficasButtonGroupSelectionChanged(app, event)
            % selectedButton = app.GrficasButtonGroup.SelectedObject;
            cad = app.CaderaButton.Value;
            rod = app.RodillaButton.Value;
            tob = app.TobilloButton.Value;

            if cad == 1
                plot(app.SubidaAxes);
                plot(app.BajadaAxes);
            elseif rod == 1
                plot(app.SubidaAxes);
                plot(app.BajadaAxes);
            elseif tob == 1
                plot(app.SubidaAxes);
                plot(app.BajadaAxes);
            else % Para limpiar el elemento axes
                app.BajadaAxes.cla;
                app.SubidaAxes.cla;
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.Tag = 'imageLogo';
            app.Image.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformeButton = uibutton(app.GridLayout4, 'push');
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.AvatarButton = uibutton(app.GridLayout4, 'push');
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PacienteLabel = uilabel(app.GridLayout6);
            app.PacienteLabel.Tag = 'labelPaciente';
            app.PacienteLabel.Text = 'Paciente:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesokgLabel = uilabel(app.GridLayout6);
            app.PesokgLabel.Tag = 'labelPeso';
            app.PesokgLabel.Text = 'Peso (kg):';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.Label = uilabel(app.GridLayout6);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout6);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GridLayout6);
            app.Label_4.Text = '';
            app.GrficasButtonGroup = uibuttongroup(app.GridLayout2);
            app.GrficasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GrficasButtonGroupSelectionChanged, true);
            app.GrficasButtonGroup.Title = 'Gráficas';
            app.GrficasButtonGroup.Tag = 'labelGraficas';
            app.CaderaButton = uiradiobutton(app.GrficasButtonGroup);
            app.CaderaButton.Tag = 'buttonCadera';
            app.CaderaButton.Text = 'Cadera';
            app.CaderaButton.Value = true;
            app.RodillaButton = uiradiobutton(app.GrficasButtonGroup);
            app.RodillaButton.Tag = 'buttonRodilla';
            app.RodillaButton.Text = 'Rodilla';
            app.TobilloButton = uiradiobutton(app.GrficasButtonGroup);
            app.TobilloButton.Tag = 'buttonTobillo';
            app.TobilloButton.Text = 'Tobillo';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SubidaAxes = uiaxes(app.GridLayout5);
            title(app.SubidaAxes, 'Subida')
            xlabel(app.SubidaAxes, 'X')
            ylabel(app.SubidaAxes, 'Y')
            zlabel(app.SubidaAxes, 'Z')
            app.SubidaAxes.Tag = 'axesSubida';
            app.BajadaAxes = uiaxes(app.GridLayout5);
            title(app.BajadaAxes, 'Bajada')
            xlabel(app.BajadaAxes, 'X')
            ylabel(app.BajadaAxes, 'Y')
            zlabel(app.BajadaAxes, 'Z')
            app.BajadaAxes.Tag = 'axesBajada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_graficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end